classdef ColorMapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AddpointtocolormapButton       matlab.ui.control.Button
        rowsincolormapEditFieldLabel   matlab.ui.control.Label
        rowsincolormapEditField        matlab.ui.control.NumericEditField
        CurrentcolormapLabel           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PointtoeditDropDownLabel       matlab.ui.control.Label
        PointtoeditDropDown            matlab.ui.control.DropDown
        interpolationpointsDropDownLabel  matlab.ui.control.Label
        interpolationpointsDropDown    matlab.ui.control.DropDown
        SavecolormaptofileButton       matlab.ui.control.Button
        Help                           matlab.ui.control.Button
        SavecolormaptoworkspaceButton  matlab.ui.control.Button
        VariablenameEditFieldLabel     matlab.ui.control.Label
        VariablenameEditField          matlab.ui.control.EditField
        CenterPanel                    matlab.ui.container.Panel
        satvalaxes                     matlab.ui.control.UIAxes
        hueaxes                        matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        CenterpositionSliderLabel      matlab.ui.control.Label
        CenterpositionSlider           matlab.ui.control.Slider
        PowerscalingSliderLabel        matlab.ui.control.Label
        PowerscalingSlider             matlab.ui.control.Slider
        cmapaxes                       matlab.ui.control.UIAxes
        swatheaxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        r % Description
        g % Description
        b % Description
        currenthue % Description
        xhue % Description
        yhue
        currentsat % Description
        currentval % Description
        current_svxy % Description
        current_HSVcolorstack % Description
        xsatvalmesh % Description
        ysatvalmesh % Description
        hsv_interp_points % The key things to populate 
        cmap
        isfirstaddition % Description
        cmap_centerpoints % Description
        nrows % Description
        scale_exponents % Description
    end
    
    methods (Access = private)
        
        function hueh = plotHueMap(app)
%             app.currenthue
            cla(app.hueaxes);
            hold(app.hueaxes,'on');
            axes(app.hueaxes);
%             scatter(app.hueaxes,[2,3,4],[1,2,3])
            hueh = imagesc(app.hueaxes,app.xhue,app.yhue,cat(3,app.r,app.b,app.g));
            app.hueaxes.XDir = 'normal';
            app.hueaxes.YDir = 'normal';
%             xlim(app.hueaxes,[0,size(app.r,1)]);
%             ylim(app.hueaxes,[0,size(app.r,2)]);
            xlim(app.hueaxes,[min(app.xhue),max(app.xhue)]);
            ylim(app.hueaxes,[min(app.xhue),max(app.xhue)]);
            app.hueaxes.Box = "off";
%             app.hueaxes.XAxis = "off";
            app.hueaxes.Color = 'none';
            app.hueaxes.XColor = 'none';
            app.hueaxes.YColor = 'none';
            theta = -(app.currenthue*2*pi-pi);   % - pi?
            rho = 0.95;
            [x,y] = pol2cart(theta,rho);
            scatter(app.hueaxes,x,y,'MarkerFaceColor','k','MarkerEdgeColor','w','LineWidth',1);
            title(app.hueaxes,'');
            
            % needs to be figured out how to get the scatter axes to
            % overlay the image axes
        end
        
        function satvalh = plotSatValMap(app)
            density = 0.005;
            cla(app.satvalaxes);
            hold(app.satvalaxes,'on');
            axes(app.satvalaxes);
            [ RGB_color_stack, HSV_color_stack ] = getTriangleColorLegend_v2(density,[app.currenthue,1,1],[app.currenthue,0,1]);
            app.current_HSVcolorstack = HSV_color_stack;
            base = 0:density:1;
            [app.xsatvalmesh,app.ysatvalmesh] = meshgrid(base,base);
            satvalh = imagesc(app.satvalaxes,base,base,RGB_color_stack);
            app.satvalaxes.XDir = 'normal';
            app.satvalaxes.YDir = 'normal';
            app.satvalaxes.Box = "off";
            app.satvalaxes.Color = 'none';
            app.satvalaxes.XColor = 'none';
            app.satvalaxes.YColor = 'none';
            title(app.satvalaxes,'');
            xlim(app.satvalaxes,[0,1]);
            ylim(app.satvalaxes,[0,1]);
            
            scatter(app.satvalaxes,app.current_svxy(1),app.current_svxy(2),'MarkerFaceColor','k','MarkerEdgeColor','w','LineWidth',1);
        end
        
        function cmaph = plotCMap(app)
            cla(app.cmapaxes);
            hold(app.cmapaxes,'on');
            axes(app.cmapaxes);
            height = size(app.cmap,1);
            width = ceil(height/10);
            impix = permute(app.cmap,[1,3,2]).*ones(height,width,3);
            impix_rgb = hsv2rgb(impix);
            cmaph = imagesc(app.cmapaxes,impix_rgb);
            ylabel(app.cmapaxes,'Colormap row')
            app.cmapaxes.XDir = 'normal';
            app.cmapaxes.YDir = 'normal';
            app.cmapaxes.Box = "off";
            app.cmapaxes.Color = 'none';
            app.cmapaxes.XColor = 'none';
            xlim(app.cmapaxes,[0,size(impix_rgb,2)]);
            ylim(app.cmapaxes,[0,size(impix_rgb,1)+0.5]);
            title(app.cmapaxes,'');
%             app.satvalaxes.YColor = 'none';
        end
        
        % older version of this function.
        function results = calculateCMap(app)
            % for now, assume linear interpolation with equal number of
            % points.
            % also needs some cleaning up so we don't double-count a point.
            % 
            npoints = size(app.hsv_interp_points,1);
            nrows = app.rowsincolormapEditField.Value;
            ninterp_segments = npoints-1;
            nrows_per_interp = nrows/(ninterp_segments);
            cmap = zeros(0,3);
            points = 1;
            for i = 1:ninterp_segments
                % determine whether distance is shorter, to positive or
                % negative rotation to hue
                huedist_1 = abs(app.hsv_interp_points(i,1) - app.hsv_interp_points(i+1,1));
                huedist_2 = abs(app.hsv_interp_points(i,1) - (app.hsv_interp_points(i+1,1)+1));
                huedist_3 = abs(app.hsv_interp_points(i,1) - (app.hsv_interp_points(i+1,1)-1));
                if huedist_2 < huedist_1
                    intpoint = app.hsv_interp_points(i+1,:) + [1,0,0];
                elseif huedist_3 < huedist_1
                    intpoint = app.hsv_interp_points(i+1,:) - [1,0,0];
                else
                    intpoint = app.hsv_interp_points(i+1,:) + [0,0,0];
                end
                intvals = interp1([1;nrows_per_interp],vertcat(app.hsv_interp_points(i,:),intpoint),(1:nrows_per_interp)');
                cmap = vertcat(cmap,mod(intvals,1));
                points = [points,points(end)+nrows_per_interp];
            end
            app.cmap = cmap;
            app.cmap_centerpoints = points;
        end
        
        % newer version of this function.
        function results = calculateCMap2(app)
            % No longer assumes linear interpolation with an equal number
            % of points.
            % First, sort the centermap and make sure everything is lined
            % up
            [vals,inds] = sort(app.cmap_centerpoints,'ascend');
            app.cmap_centerpoints = vals;
            app.hsv_interp_points = app.hsv_interp_points(inds,:);
            
            npoints = size(app.hsv_interp_points,1);
            thisnrows = app.nrows;
            ninterp_segments = npoints-1;
%             nrows_per_interp = nrows/(ninterp_segments);
            thiscmap = zeros(thisnrows,3);
%             points = 1;
            for i = 1:ninterp_segments
                % determine the distance that should be replaced
                firstrow = app.cmap_centerpoints(i);
                secondrow = app.cmap_centerpoints(i+1);
                if firstrow == secondrow
                    continue  % would cause a problem with non-unique values in the interpolation,
                    % but not necessary because this row will already have
                    % been filled in. 
                end
                seginds = firstrow:secondrow;
                
                % determine whether distance is shorter, to positive or
                % negative rotation to hue
                huedist_1 = abs(app.hsv_interp_points(i,1) - app.hsv_interp_points(i+1,1));
                huedist_2 = abs(app.hsv_interp_points(i,1) - (app.hsv_interp_points(i+1,1)+1));
                huedist_3 = abs(app.hsv_interp_points(i,1) - (app.hsv_interp_points(i+1,1)-1));
                if huedist_2 < huedist_1
                    intpoint = app.hsv_interp_points(i+1,:) + [1,0,0];
                elseif huedist_3 < huedist_1
                    intpoint = app.hsv_interp_points(i+1,:) - [1,0,0];
                else
                    intpoint = app.hsv_interp_points(i+1,:) + [0,0,0];
                end
                disp(i);
                %%%%% Previous version not admitting nonlinear scaling
% % % % %                 intvals = interp1([firstrow;secondrow],vertcat(app.hsv_interp_points(i,:),intpoint),(seginds)');
                %%%%% New version admitting non linear scaling, so entirely
                %%%%% in terms of deltas.
                delta = intpoint - app.hsv_interp_points(i,:);
                weights = linspace(0,1,numel(seginds))';
                weights = weights.^app.scale_exponents(i);  % so the last scaling one doesn't do anything.
                to_add = delta.*weights;
                intvals = app.hsv_interp_points(i,:) + to_add;
                %                 cmap = vertcat(cmap,mod(intvals,1));
                intvals(:,1) = mod(intvals(:,1),1);
                thiscmap(seginds,:,:) = intvals;
%                 points = [points,points(end)+nrows_per_interp];
            end
            app.cmap = thiscmap;
%             app.cmap_centerpoints = points;
        end
        
        function swatheh = plotSwatheMap(app)
            cla(app.swatheaxes);
            hold(app.swatheaxes,'on');
            axes(app.swatheaxes);
            totheight = size(app.cmap,1);
            sheight = ceil(totheight/20);
            delta = floor(sheight/2);
%             totwidth = sheight;
            totwidth = 10;
            impix_rgb = cat(3,0.94*ones(totheight,totwidth),0.94*ones(totheight,totwidth),0.94*ones(totheight,totwidth));
%             impix = permute(app.cmap,[1,3,2]).*ones(height,width,3);
%             impix_rgb = hsv2rgb(impix);
            % Build the swathe image 
            maxrow = totheight;
            minrow = 1;
            for i = 1:size(app.hsv_interp_points,1)
                thiscolor_rgb = hsv2rgb(app.hsv_interp_points(i,:));
                % calculate range of impix_rgb that should be replaced.
                centerrow = app.cmap_centerpoints(i);
                topcand = centerrow + delta;
                bottomcand = centerrow - delta;
                if topcand > maxrow
                    topr = maxrow;
                else
                    topr = topcand;
                end
                if bottomcand < minrow
                    botr = minrow;
                else
                    botr = bottomcand;
                end
                % vertical column of pixels to replace with this color is
                % now botr:topr
                impix_rgb(botr:topr,:,:) = permute(thiscolor_rgb,[1,3,2]).*ones(size(impix_rgb(botr:topr,:,:)));
                % add label text for this swathe
                text(app.swatheaxes,-2,centerrow,num2str(i));
            end
            swatheh = imagesc(app.swatheaxes,impix_rgb);
            app.swatheaxes.XDir = 'normal';
            app.swatheaxes.YDir = 'normal';
            app.swatheaxes.Box = "off";
            app.swatheaxes.Color = 'none';
            app.swatheaxes.XColor = 'none';
            app.swatheaxes.YColor = 'none';
            xlim(app.swatheaxes,[-3,totwidth]);
            ylim(app.swatheaxes,[0,maxrow+0.5]);
            title(app.swatheaxes,'');
%             title(app.cmapaxes,'');
        end
        
        function results = updateCenterpositionSliderDefaults(app)
            thisentry = str2double(app.PointtoeditDropDown.Value);
            % The old way of doing this let you go the full range, e.g.
            % from 1 to 256.
%             app.CenterpositionSlider.Limits = [1,app.rowsincolormapEditField.Value];
            % New way: you can go no lower than the next lowest
            % centerposition, for example. 
            all_centerpoints = app.cmap_centerpoints;
%             current_entry = str2double(app.PointtoeditDropDown);
            use_points = all_centerpoints;
            use_points(thisentry) = [];
%             use_points = [use_points,1,app.rowsincolormapEditField.Value];
            value_before_change = all_centerpoints(thisentry);
            arelt = value_before_change > use_points;
            aregt = value_before_change < use_points;
            areeq = value_before_change == use_points;
            % Still not completely sure how to handle equality.
            % approach: figure out the direction the slide came from.
            % if from above, then this counts as less than
            % if from below, counts as greater than
            % Do this determination on the basis of the index
            if thisentry ~= 1 && thisentry ~= size(all_centerpoints,1)   % those will still be hard
                areeq_lowerside = false(size(areeq));
                areeq_upperside = false(size(areeq));
                areeq_lowerside(1:thisentry-1) = areeq(1:thisentry-1);
                areeq_upperside(thisentry:end) = areeq(thisentry:end);
                arelt = arelt | areeq_lowerside;
                aregt = aregt | areeq_upperside;
            end
            
            smallest = max(use_points(arelt));
            largest = min(use_points(aregt));
            if isempty(smallest)
                smallest = 1;
            end
            if isempty(largest)
                largest = app.rowsincolormapEditField.Value;
            end
            app.CenterpositionSlider.Limits = [smallest,largest];
            app.CenterpositionSlider.Value = app.cmap_centerpoints(thisentry);
            
            % Additionally update the position of the nonlinear slider
            app.PowerscalingSlider.Value = log10(app.scale_exponents(thisentry));
            if thisentry == numel(app.scale_exponents)
                app.PowerscalingSlider.Visible = false;
                app.PowerscalingSliderLabel.Visible = false;
            else
                app.PowerscalingSlider.Visible = true;
                app.PowerscalingSliderLabel.Visible = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.isfirstaddition = true;
            app.currenthue = 1;
            app.currentsat = 1;
            app.currentval = 1;
            app.hsv_interp_points = [0,0,0;0,0,1];
            app.scale_exponents = [1,1];
            app.nrows = app.rowsincolormapEditField.Value;
            app.cmap_centerpoints = [1,app.nrows];
            app.UITable.Data = app.hsv_interp_points;
            app.current_svxy = [0.5,sqrt(3)/2];
            n = 200;
            rectbase = linspace(-1,1,n);
            [xspace,yspace] = meshgrid(rectbase,rectbase);
            app.xhue = rectbase;
            app.yhue = rectbase;
            [theta,rho] = cart2pol(xspace,yspace);
            goodinds = (rho <= 1) & (rho >= 0.9);
            hues = (theta + pi)/(2*pi);
            sats = ones(size(hues));
            vals = ones(size(hues));
            [rgb] = hsv2rgb([hues(:),sats(:),vals(:)]);
            rgb(~goodinds,:) = 0.94;
            app.r = reshape(rgb(:,1),size(hues));
            app.g = reshape(rgb(:,2),size(hues));
            app.b = reshape(rgb(:,3),size(hues));
            calculateCMap2(app);
            refreshPlots(app);
%             hueh = app.plotHueMap();
            % figure;
            % imshow(cat(3,r,g,b));
            
            % buttondown callbacks have to be defined nested in the startup
            % function (this seems really clunky but whatever)
%             https://www.mathworks.com/matlabcentral/answers/508598-how-do-i-use-the-buttondownfcn-for-a-plot-in-app-designer
% fun = @(app,event) HueFigButtonDown(app,event)


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x', 295};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button down function: hueaxes
        function refreshPlots(app, event)
%             calculateCMap(app);
            app.updateCenterpositionSliderDefaults();
            calculateCMap2(app);
            hueh = app.plotHueMap();
            satvalh = app.plotSatValMap();
            cmaph = app.plotCMap();
            swatheh = app.plotSwatheMap();
            set(hueh, 'ButtonDownFcn', @HueFigButtonDown);
            set(satvalh, 'ButtonDownFcn', @SatValFigButtonDown);
            drawnow;
            
            function HueFigButtonDown(~,event)
                disp('Button down triggered')
                % get the IntersectionCoordinates of the hit to compute new
                % hue angle. 
                xy = event.IntersectionPoint(1:2);
                [thetat,rhot] = cart2pol(xy(1),xy(2));
                app.currenthue = -(thetat - pi)/(2*pi)
                app.refreshPlots();
                disp('done')
            end
            
            function SatValFigButtonDown(~,event)
                disp('Button down sat val');
                xy = event.IntersectionPoint(1:2)
                app.current_svxy = xy;
                hsv = app.current_HSVcolorstack;
                badinds = hsv(:,:,3) == 0.94;
                h = hsv(:,:,1);
                s = hsv(:,:,2);
                v = hsv(:,:,3);
                h(badinds) = nan;
                s(badinds) = nan;
                v(badinds) = nan;
                hsv = cat(3,h,s,v);
%                 x = app.xsatvalmesh(:);
%                 y = app.ysatvalmesh(:);
%                 hnearest = interp2(app.xsatvalmesh,app.ysatvalmesh,hsv(:,:,1),xy(1),xy(2))
                snearest = interp2(app.xsatvalmesh,app.ysatvalmesh,hsv(:,:,2),xy(1),xy(2))
                vnearest = interp2(app.xsatvalmesh,app.ysatvalmesh,hsv(:,:,3),xy(1),xy(2))
                app.currentsat = snearest;
                app.currentval = vnearest;
                app.refreshPlots();
                % get nearest point on the HSV colormap with 
            end
            
            
        end

        % Button pushed function: AddpointtocolormapButton
        function AddpointtocolormapButtonPushed(app, event)
            disp('adding point to colormap');
            v = str2num(app.PointtoeditDropDown.Value);
            app.hsv_interp_points(v,:) = [app.currenthue,app.currentsat,app.currentval];
            if app.isfirstaddition
                app.hsv_interp_points(:,1) = app.currenthue;
                app.isfirstaddition = false;
            end
%             app.hsv_interp_points = vertcat(app.hsv_interp_points,[app.currenthue,app.currentsat,app.currentval]);
            app.UITable.Data = app.hsv_interp_points;
            app.refreshPlots();
        end

        % Value changed function: interpolationpointsDropDown
        function interpolationpointsDropDownValueChanged(app, event)
            value = app.interpolationpointsDropDown.Value;
            value = str2num(value);
            if value < str2num(app.PointtoeditDropDown.Value)
                app.PointtoeditDropDown.Value = num2str(value);
            end
            cells = mat2cell(1:value,size(1:value,1),ones(size(1:value)));
            for i = 1:numel(cells)
                cells{i} = num2str(cells{i});
            end
            app.PointtoeditDropDown.Items = cells;
            
            % Need to modify app.cmap_centerpoints in response to changing
            % the number of colormap points
            oldpoints = app.cmap_centerpoints;
            old_npoints = numel(app.cmap_centerpoints);
            new_npoints = value;
            diffpoints = new_npoints - old_npoints;
            if new_npoints < old_npoints
                % truncate points
                app.cmap_centerpoints = oldpoints(1:new_npoints);
                app.scale_exponents = app.scale_exponents(1:new_npoints);
            elseif new_npoints > old_npoints
                % scale down the old points to their fractional
                % representation of the total map
                ratio = (old_npoints-1) / (new_npoints-1);
                newpoints = round(oldpoints * ratio);
                newpoints(1) = 1;
                % evenly distribute added points in the gap.
                maxpoint = max(newpoints);
                finalpoint = app.nrows;
                evenspace = round(linspace(maxpoint,finalpoint,diffpoints + 1));
                newpoints = [newpoints,evenspace(2:end)];
                newpoints(end) = finalpoint;
                app.cmap_centerpoints = newpoints;
                app.hsv_interp_points((end+1):(end+diffpoints),:) = repmat(app.hsv_interp_points(end,:),diffpoints,1);
                app.scale_exponents = horzcat(app.scale_exponents,ones(1,diffpoints));
            end
            app.refreshPlots();
        end

        % Value changed function: CenterpositionSlider
        function CenterpositionSliderValueChanged(app, event)
            entry_to_change = str2double(app.PointtoeditDropDown.Value);
            value = round(app.CenterpositionSlider.Value);
            if value < 1
                value = 1;
            end
            app.cmap_centerpoints(entry_to_change) = value;
            app.refreshPlots();
        end

        % Value changed function: PointtoeditDropDown
        function PointtoeditDropDownValueChanged(app, event)
%             value = app.PointtoeditDropDown.Value;
            app.updateCenterpositionSliderDefaults();
        end

        % Button pushed function: SavecolormaptofileButton
        function SavecolormaptofileButtonPushed(app, event)
            
            [filename,path,idx] = uiputfile({'.mat';'.csv'},'Save colormap');
            if filename ~= 0
                cmap = app.cmap;
                cmap_rgb = hsv2rgb(cmap);
                curdir = pwd;
                cd(path);
                switch idx
                    case 1 % .mat
                        save(filename,'cmap_rgb');
                    case 2
                        writematrix(cmap_rgb,filename);
                end
                %             cmaph = plotCMap(app);
                a = strsplit(filename,'.');
                basename = a{1};
                exportgraphics(app.cmapaxes,sprintf('%s.png',basename))
                %             saveas(,);
                cd(curdir);
            end
        end

        % Value changed function: rowsincolormapEditField
        function rowsincolormapEditFieldValueChanged(app, event)
            oldrows = app.nrows;
            app.nrows = app.rowsincolormapEditField.Value;
            newrows = app.nrows;
            ratio = newrows/oldrows;
            app.cmap_centerpoints = round(app.cmap_centerpoints*ratio);
            app.cmap_centerpoints(1) = 1;
            app.cmap_centerpoints(end) = app.nrows;
            app.refreshPlots();            
        end

        % Button pushed function: SavecolormaptoworkspaceButton
        function SavecolormaptoworkspaceButtonPushed(app, event)
            assignin('base',app.VariablenameEditField.Value,app.cmap);
        end

        % Value changed function: PowerscalingSlider
        function PowerscalingSliderValueChanged(app, event)
            currententry = str2double(app.PointtoeditDropDown.Value);
            value = app.PowerscalingSlider.Value;
            app.scale_exponents(currententry) = 10.^value;
            app.refreshPlots();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            text = sprintf(['App created by Nathanael Kazmierczak, 06/29/2023\n' ...
                'kazmierczak314@gmail.com\n\n' ...
                'The Colormap App generates a custom colormap that can be used like any of the default Matlab colormaps to plot data. ' ...
                'The user selects two or more points, and the app interpolates between them to generate the colormap. ' ...
                'The points are selected in the hue/saturation/value (HSV) parameters, which are an alternative way of describing the RGB values. ' ...
                'For example, to set the color of the second interpolation point to a dark orange, select "2" from the drop-down "Point to Edit" menu. ' ...
                'Then click on the color ring near the orange hue. A black dot will appear where you have clicked, indicating the selected hue. ' ...
                'Next, click on underlying triangle to select the desired dark orange shade (the triangle controls the saturation and value parameters). ' ...
                'Finally, press the "Add Point to Colormap" button. You should see the colormap in the right panel update with the new color. \n\n' ...
                'You can change the total length of the colormap in rows and the number of points for the interpolation by the appropriate fields in the left panel. ' ...
                'The "Center Position" slider bar on the right panel allows you to change the relative position in the colormap of the different colors to be interpolated. ' ...
                'This is not useful for a two color map, where the colors necessarily sit on the edges of the colormap and all colors in-between are smoothly interpolated. ' ...
                'However, suppose you want to generate a more complex colormap, such as a diverging colormap where two different hues are connected by a neutral tone such as grey or white. ' ...
                'To do so, you would specify 4 points to interpolate in the colormap. Set point 1 to the first color (e.g. saturated red), and point 4 to the second color (e.g. saturated blue). ' ...
                'Point 2 should be set to the same hue as point 1 (red), but completely desaturated in the S/V triangle to yield the neutral white/grey tone. ' ...
                'Point 3 should be set to the same hue as point 4 (blue), but again desaturated. This means the visible colors of points 2 and 3 match, even though the hues are coded differently. ' ...
                'This ensures a smooth saturation/value interpolation between points 1 and 2 and points 3 and 4 without changing the hue. ' ...
                'Finally, we need to contract the region of white in the middle by using the sliders. Selecting point 2 to edit from the drop down menu, move the "Center position" slider bar up to row 128 (for a 256 row colormap). ' ...
                'In the right panel, you should see the second interpolation point graphically moving. It is labeled to the right of the colormap itself. ' ...
                'Then, select the third point to edit and move the center position down by the slider also to 128. The white regions will coalesce, and a smooth diverging colormap will be formed. \n\n' ...
                'Additional options: By using the "Power scale" slide bar, you can add nonlinearity to a color interpolation to make a color of your choice cover a broader region. ' ...
                'Between two interpolation points, the stepped changes in HSV are computed as (linspace(point1,point2,n).^(10^powerscale)). Thus, setting powerscale to 0 gives a linear interpolation, ' ...
                'while powerscale < 0 will emphasize the second color and powerscale > 0 will emphasize the first color. ' ...
                'Note that when point 1 is selected, the power scale slider controls the nonlinear interpolation between point 1 and 2. Thus, the power scale slide for the final point does not have any effect. ' ...
                'Finally, the "Save Colormap to File" button allows you to save the generated map as a .csv or .mat, while "Save colormap to workspace" will save the map as a variable with the specified name. ' ...
                'This is useful for quickly generating new colormaps for use in a script.'])
            uialert(app.UIFigure,text,'Help for Colormap App')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddpointtocolormapButton = uibutton(app.LeftPanel, 'push');
            app.AddpointtocolormapButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointtocolormapButtonPushed, true);
            app.AddpointtocolormapButton.Text = 'Add point to colormap';
            app.rowsincolormapEditFieldLabel = uilabel(app.LeftPanel);
            app.rowsincolormapEditFieldLabel.Text = '# rows in colormap';
            app.rowsincolormapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rowsincolormapEditField.ValueChangedFcn = createCallbackFcn(app, @rowsincolormapEditFieldValueChanged, true);
            app.rowsincolormapEditField.Value = 256;
            app.CurrentcolormapLabel = uilabel(app.LeftPanel);
            app.CurrentcolormapLabel.Text = 'Current colormap:';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'hue'; 'saturation'; 'value'};
            app.UITable.RowName = {};
            app.PointtoeditDropDownLabel = uilabel(app.LeftPanel);
            app.PointtoeditDropDownLabel.Text = 'Point to edit';
            app.PointtoeditDropDown = uidropdown(app.LeftPanel);
            app.PointtoeditDropDown.Items = {'1', '2'};
            app.PointtoeditDropDown.ValueChangedFcn = createCallbackFcn(app, @PointtoeditDropDownValueChanged, true);
            app.PointtoeditDropDown.Value = '1';
            app.interpolationpointsDropDownLabel = uilabel(app.LeftPanel);
            app.interpolationpointsDropDownLabel.Text = '# interpolation points';
            app.interpolationpointsDropDown = uidropdown(app.LeftPanel);
            app.interpolationpointsDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.interpolationpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @interpolationpointsDropDownValueChanged, true);
            app.interpolationpointsDropDown.Value = '2';
            app.SavecolormaptofileButton = uibutton(app.LeftPanel, 'push');
            app.SavecolormaptofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavecolormaptofileButtonPushed, true);
            app.SavecolormaptofileButton.Text = 'Save colormap to file';
            app.Help = uibutton(app.LeftPanel, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.SavecolormaptoworkspaceButton = uibutton(app.LeftPanel, 'push');
            app.SavecolormaptoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavecolormaptoworkspaceButtonPushed, true);
            app.SavecolormaptoworkspaceButton.Text = 'Save colormap to workspace';
            app.VariablenameEditFieldLabel = uilabel(app.LeftPanel);
            app.VariablenameEditFieldLabel.Text = 'Variable name';
            app.VariablenameEditField = uieditfield(app.LeftPanel, 'text');
            app.VariablenameEditField.Value = 'cmap';
            app.CenterPanel = uipanel(app.GridLayout);
            app.satvalaxes = uiaxes(app.CenterPanel);
            title(app.satvalaxes, 'Title')
            xlabel(app.satvalaxes, 'X')
            ylabel(app.satvalaxes, 'Y')
            zlabel(app.satvalaxes, 'Z')
            app.hueaxes = uiaxes(app.CenterPanel);
            title(app.hueaxes, 'Title')
            xlabel(app.hueaxes, 'X')
            ylabel(app.hueaxes, 'Y')
            zlabel(app.hueaxes, 'Z')
            app.hueaxes.ButtonDownFcn = createCallbackFcn(app, @refreshPlots, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.CenterpositionSliderLabel = uilabel(app.RightPanel);
            app.CenterpositionSliderLabel.Text = 'Center position';
            app.CenterpositionSlider = uislider(app.RightPanel);
            app.CenterpositionSlider.Limits = [1 256];
            app.CenterpositionSlider.ValueChangedFcn = createCallbackFcn(app, @CenterpositionSliderValueChanged, true);
            app.CenterpositionSlider.Value = 1;
            app.PowerscalingSliderLabel = uilabel(app.RightPanel);
            app.PowerscalingSliderLabel.Text = 'Power scaling';
            app.PowerscalingSlider = uislider(app.RightPanel);
            app.PowerscalingSlider.Limits = [-1 1];
            app.PowerscalingSlider.ValueChangedFcn = createCallbackFcn(app, @PowerscalingSliderValueChanged, true);
            app.cmapaxes = uiaxes(app.RightPanel);
            title(app.cmapaxes, 'Title')
            xlabel(app.cmapaxes, 'X')
            ylabel(app.cmapaxes, 'Y')
            zlabel(app.cmapaxes, 'Z')
            app.swatheaxes = uiaxes(app.RightPanel);
            title(app.swatheaxes, 'Title')
            xlabel(app.swatheaxes, 'X')
            ylabel(app.swatheaxes, 'Y')
            zlabel(app.swatheaxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorMapApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end